classdef PV3eTelem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelemetriePV3eUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        ConsommationPanel            matlab.ui.container.Panel
        ConsommationMoteurElecLabel  matlab.ui.control.Label
        ConsommationCarburantLabel   matlab.ui.control.Label
        InfoenvoyerPanel             matlab.ui.container.Panel
        TargetSOCEditField           matlab.ui.control.NumericEditField
        TargetSOCEditFieldLabel      matlab.ui.control.Label
        n1deltaEditField             matlab.ui.control.NumericEditField
        n1deltaEditFieldLabel        matlab.ui.control.Label
        livedeltaEditField           matlab.ui.control.NumericEditField
        livedeltaEditFieldLabel      matlab.ui.control.Label
        racedeltaEditField           matlab.ui.control.NumericEditField
        racedeltaEditFieldLabel      matlab.ui.control.Label
        TurnICEEditField             matlab.ui.control.NumericEditField
        TurnICEEditFieldLabel        matlab.ui.control.Label
        TargetSpeedEditField         matlab.ui.control.NumericEditField
        TargetSpeedEditFieldLabel    matlab.ui.control.Label
        TurnRegenEditField           matlab.ui.control.NumericEditField
        TurnRegenEditFieldLabel      matlab.ui.control.Label
        EnvoyerButton                matlab.ui.control.Button
        ConfigurationmoteurPanel     matlab.ui.container.Panel
        PotentiometreKnob            matlab.ui.control.DiscreteKnob
        PotentiometreKnobLabel       matlab.ui.control.Label
        ModeMoteurLabel              matlab.ui.control.Label
        ComServeurPanel              matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        LampLabel                    matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        VitessePanel                 matlab.ui.container.Panel
        DistanceparcourueLabel       matlab.ui.control.Label
        VitesseGauge                 matlab.ui.control.Gauge
        VitesseGaugeLabel            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        MoteurThermiquePanel         matlab.ui.container.Panel
        TensionLiPoLabel             matlab.ui.control.Label
        PressionCarbuLabel           matlab.ui.control.Label
        TempsInjectionLabel          matlab.ui.control.Label
        lambdaLabel                  matlab.ui.control.Label
        TempeauLabel                 matlab.ui.control.Label
        RegimeThermqueLabel          matlab.ui.control.Label
        MoteurElectriquePanel        matlab.ui.container.Panel
        TensionMoteurelecLabel       matlab.ui.control.Label
        TemperatureMoteurelecLabel   matlab.ui.control.Label
        TemperatureVESCLabel         matlab.ui.control.Label
        CoupleElecLabel              matlab.ui.control.Label
        TensionsuperCLabel           matlab.ui.control.Label
        RegimeElectriqueLabel        matlab.ui.control.Label
        IndicateurPanel              matlab.ui.container.Panel
        RegenLamp                    matlab.ui.control.Lamp
        RegenLampLabel               matlab.ui.control.Label
        DeadmanLamp                  matlab.ui.control.Lamp
        DeadmanLampLabel             matlab.ui.control.Label
        DmarreurLamp                 matlab.ui.control.Lamp
        DmarreurLampLabel            matlab.ui.control.Label
        FUELLamp                     matlab.ui.control.Lamp
        FUELLampLabel                matlab.ui.control.Label
        VentilationLamp              matlab.ui.control.Lamp
        VentilationLampLabel         matlab.ui.control.Label
        ClignoDLamp                  matlab.ui.control.Lamp
        ClignoDLampLabel             matlab.ui.control.Label
        EssuieLamp                   matlab.ui.control.Lamp
        EssuieLampLabel              matlab.ui.control.Label
        LumierehabLamp               matlab.ui.control.Lamp
        LumierehabLampLabel          matlab.ui.control.Label
        FeuxDetresseLamp             matlab.ui.control.Lamp
        FeuxDetresseLampLabel        matlab.ui.control.Label
        ClignoGLamp                  matlab.ui.control.Lamp
        ClignoGLampLabel             matlab.ui.control.Label
        PhareLamp                    matlab.ui.control.Lamp
        PhareLampLabel               matlab.ui.control.Label
        Tab2                         matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serverOBJ % Description
        tcps
        run=0
        filedata
        fileindex = 1;
        dataSize = 29;
        xs
        ysV
        ysCourant
        ysTension
        ysRegTh
        ysRegElec
        index = 1
        
    end
    
    methods (Access = public)
        
        
        function initServer(app)
            app.run = 1;
            %on cree une instance de TCPServer (contient les fonction utile
            %pour la comm TCP)
            app.serverOBJ = TCPServer;
            
            address = "46.105.28.70";
            port = 11000
            
            %on lance le client TCP
            try 
                app.tcps = tcpclient(address, port); 
            catch 
                app.run = 0;
                return
            end
            writeline(app.tcps, "client");
            app.EnvoyerButton.Enable = true;
            
            app.Lamp.Color = getColor(app,1);
            
            while(app.run == 1)
                 %on regarde si on a reçu des données
                 if app.tcps.NumBytesAvailable > 0
                    %on stock les données reçu dans data
                    data = read(app.tcps,app.tcps.NumBytesAvailable,"uint8");
                    len = size(data);
                    len = len(2);
                    if len>=32
                        %on appel updateData qui decode les données brut en valeurs
                        r = app.serverOBJ.updateData(data, app.dataSize);
                        %on enregistre les données
                        appendData(app, r);
                        %on met a jour l'affichage
                        updateGUI(app, r);
                    end
                 end
                 
                %obligatoire pour eviter de bloquer le programme
                drawnow();
            end
            c = clock()
            %on enregistre les données dans un fichier csv
            writematrix(app.filedata, "log_"+c(4) + "_" +c(5) + "_" + ceil(c(6))+".csv")
            writeline(app.tcps, "END");
            %on ferme la connexion
            clear app.tcps;
            
            
            app.Lamp.Color = getColor(app,0);
        end
        
        %récuperation des données a envoyer au raspberry
        function sdata = getDataToSend(app)
                    turn_regen = app.TurnRegenEditField.Value
                    target_speed = app.TargetSpeedEditField.Value
                    turn_ice = app.TurnICEEditField.Value
                    race_delta = app.racedeltaEditField.Value
                    live_delta = app.livedeltaEditField.Value
                    n_1 = app.n1deltaEditField.Value
                    target_soc = app.TargetSOCEditField.Value
                    
                    sdata = zeros(1,12)
                    sdata(1) = (turn_regen)
                    sdata(2) = (target_speed*10)
                    sdata(3) = (turn_ice)
                    sdata(4) = (race_delta*10)
                    sdata(5) = (live_delta*10)
                    sdata(6) = (n_1*10)
                    sdata(7) = (target_soc)
        end
        
        
        function c = getColor(~, boo)
            if boo == 0
                c = [0 0 0];
            else
                c = [0 1 0];
            end
        end


        function appendData(app, data)
            app.filedata(app.fileindex,:) = data;
            app.fileindex = app.fileindex + 1;
            
        end
        
        function sendData(app, data)
            len = size(data);
            len = len(2);
            write(app.tcps, int16(data))
            
        end
        
        function updateGUI(app, data)
            
            
            app.RegimeThermqueLabel.Text = "Régime thermique : "+data(1)+" tr/min";
            app.TempeauLabel.Text = "Temperature Eau : "+data(2) + " °C";
            app.lambdaLabel.Text = "Lambda : "+data(3) + "";
            app.PressionCarbuLabel.Text = "Pression carburant : "+ data(5) + " bar";
            app.TempsInjectionLabel.Text = "Temps injection : "+data(4) + " ms";
            app.TensionLiPoLabel.Text = "Tension LiPo : "+data(6) + " V";
            
            app.RegimeElectriqueLabel.Text = "Regime Moteur : " + data(7) + " tr/min";
            app.TensionsuperCLabel.Text = "Tension superC : " + data(8)+ " V";
            app.CoupleElecLabel.Text = "Couple elec: "+data(9) + " N.m";
            app.TemperatureVESCLabel.Text = "Temperature VESC : "+data(10)+ " °C";
            app.TensionMoteurelecLabel.Text = "Tension Moteur Elec : "+data(27)+" V";
            
            app.Label.Text = data(11) + "km/h";
            app.DistanceparcourueLabel.Text = "Distance parcourue : "+data(12)+" m";
            app.VitesseGauge.Value = data(11);
            
            app.PhareLamp.Color = getColor(app, data(13));
            app.EssuieLamp.Color = getColor(app, data(14));
            app.ClignoGLamp.Color = getColor(app, data(15));
            app.ClignoDLamp.Color = getColor(app, data(16));
            app.FeuxDetresseLamp.Color = getColor(app,data(17));
            app.VentilationLamp.Color = getColor(app, data(18));
            app.LumierehabLamp.Color = getColor(app, data(19));
            app.FUELLamp.Color = getColor(app,data(20));
            app.DmarreurLamp.Color= getColor(app,data(21));
            app.DeadmanLamp.Color = getColor(app,data(22));
            app.RegenLamp.Color= getColor(app,data(23));
            
            %on verifie que la valeur est valide
            potval = min(max(1,data(24)),size(app.PotentiometreKnob.Items,2));
            app.PotentiometreKnob.Value = app.PotentiometreKnob.Items(potval);
            
            app.ModeMoteurLabel.Text = "Mode Moteur : "+data(25);
            
            app.TemperatureMoteurelecLabel.Text = "Temperature Moteur elec : "+data(26)+ "°C";
            
            app.ConsommationMoteurElecLabel.Text = "Consommation Electrique : "+ data(29) +" Wh";
            
            %################# Formule Consommation Carburant ###############
            consoCarbu = 27.9;
            app.ConsommationCarburantLabel.Text = "Consommation Carburant : "+ consoCarbu + " mL"


            %################# Axes ##################
            
            app.xs(app.index) = app.index
            app.ysV(app.index) = data(11)
            app.ysCourant(app.index) = data(28)
            app.ysRegTh(app.index) = data(1)
            app.ysRegElec(app.index) = data(7)
            app.ysTension(app.index) = data(27)
            
            plot(app.UIAxes,app.xs,app.ysV)
            axis(app.UIAxes,[max(0,app.index-100),app.index+10,min(app.ysV(max(1,app.index-100):app.index))-1,max(app.ysV(max(1,app.index-100):app.index))+1])
            
            plot(app.UIAxes_2,app.xs,app.ysCourant)
            axis(app.UIAxes_2,[max(0,app.index-100),app.index+10,min(app.ysCourant(max(1,app.index-100):app.index))-1,max(app.ysCourant(max(1,app.index-100):app.index))+1])
            
            plot(app.UIAxes_3,app.xs,app.ysRegTh)
            axis(app.UIAxes_3,[max(0,app.index-100),app.index+10,min(app.ysRegTh(max(1,app.index-100):app.index))-1,max(app.ysRegTh(max(1,app.index-100):app.index))+1])
            
            plot(app.UIAxes_4,app.xs,app.ysRegElec)
            axis(app.UIAxes_4,[max(0,app.index-100),app.index+10,min(app.ysRegElec(max(1,app.index-100):app.index))-1,max(app.ysRegElec(max(1,app.index-100):app.index))+1])
            
            plot(app.UIAxes_5,app.xs,app.ysTension)
            axis(app.UIAxes_5,[max(0,app.index-100),app.index+10,min(app.ysTension(max(1,app.index-100):app.index))-1,max(app.ysTension(max(1,app.index-100):app.index))+1])
            
            
            app.index = app.index + 1;
            
            %########################################
            
        end
      
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TelemetriePV3eUIFigure.WindowState = 'maximized';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.run==0
                initServer(app);
            end
        end

        % Close request function: TelemetriePV3eUIFigure
        function TelemetriePV3eUIFigureCloseRequest(app, event)
            
            if app.run == 1
                app.run = 0;
                writeline(app.tcps, "END");
            end
            delete(app);
        end

        % Button down function: TelemetriePV3eUIFigure
        function TelemetriePV3eUIFigureButtonDown(app, event)
 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.run = 0;
        end

        % Callback function
        function EnvoyerButtonPushed(app, event)
            writeline(app.tcps, app.TurnRegenEditField.Value);
            app.TurnRegenEditField.Value = '';
        end

        % Button pushed function: EnvoyerButton
        function EnvoyerButtonPushed2(app, event)
            app.sendData(app.getDataToSend())
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TelemetriePV3eUIFigure = uifigure('Visible', 'off');
            app.TelemetriePV3eUIFigure.Name = 'Telemetrie PV3e';
            app.TelemetriePV3eUIFigure.CloseRequestFcn = createCallbackFcn(app, @TelemetriePV3eUIFigureCloseRequest, true);
            app.TelemetriePV3eUIFigure.ButtonDownFcn = createCallbackFcn(app, @TelemetriePV3eUIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.TelemetriePV3eUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.IndicateurPanel = uipanel(app.Tab);
            app.IndicateurPanel.Title = 'Indicateur';
            app.PhareLampLabel = uilabel(app.IndicateurPanel);
            app.PhareLampLabel.Text = 'Phare';
            app.PhareLamp = uilamp(app.IndicateurPanel);
            app.ClignoGLampLabel = uilabel(app.IndicateurPanel);
            app.ClignoGLampLabel.Text = 'Cligno G';
            app.ClignoGLamp = uilamp(app.IndicateurPanel);
            app.FeuxDetresseLampLabel = uilabel(app.IndicateurPanel);
            app.FeuxDetresseLampLabel.Text = 'Feux Detresse';
            app.FeuxDetresseLamp = uilamp(app.IndicateurPanel);
            app.LumierehabLampLabel = uilabel(app.IndicateurPanel);
            app.LumierehabLampLabel.Text = 'Lumiere hab';
            app.LumierehabLamp = uilamp(app.IndicateurPanel);
            app.EssuieLampLabel = uilabel(app.IndicateurPanel);
            app.EssuieLampLabel.Text = 'Essuie';
            app.EssuieLamp = uilamp(app.IndicateurPanel);
            app.ClignoDLampLabel = uilabel(app.IndicateurPanel);
            app.ClignoDLampLabel.Text = 'Cligno D';
            app.ClignoDLamp = uilamp(app.IndicateurPanel);
            app.VentilationLampLabel = uilabel(app.IndicateurPanel);
            app.VentilationLampLabel.Text = 'Ventilation';
            app.VentilationLamp = uilamp(app.IndicateurPanel);
            app.FUELLampLabel = uilabel(app.IndicateurPanel);
            app.FUELLampLabel.Text = {'FUEL'; ''};
            app.FUELLamp = uilamp(app.IndicateurPanel);
            app.DmarreurLampLabel = uilabel(app.IndicateurPanel);
            app.DmarreurLampLabel.Text = 'Démarreur';
            app.DmarreurLamp = uilamp(app.IndicateurPanel);
            app.DeadmanLampLabel = uilabel(app.IndicateurPanel);
            app.DeadmanLampLabel.Text = 'Deadman';
            app.DeadmanLamp = uilamp(app.IndicateurPanel);
            app.RegenLampLabel = uilabel(app.IndicateurPanel);
            app.RegenLampLabel.Text = 'Regen';
            app.RegenLamp = uilamp(app.IndicateurPanel);
            app.MoteurElectriquePanel = uipanel(app.Tab);
            app.MoteurElectriquePanel.Title = 'Moteur Electrique';
            app.RegimeElectriqueLabel = uilabel(app.MoteurElectriquePanel);
            app.RegimeElectriqueLabel.Text = {'Regime Electrique'; ''};
            app.TensionsuperCLabel = uilabel(app.MoteurElectriquePanel);
            app.TensionsuperCLabel.Text = 'Tension superC';
            app.CoupleElecLabel = uilabel(app.MoteurElectriquePanel);
            app.CoupleElecLabel.Text = 'Couple Elec';
            app.TemperatureVESCLabel = uilabel(app.MoteurElectriquePanel);
            app.TemperatureVESCLabel.Text = 'Temperature VESC';
            app.TemperatureMoteurelecLabel = uilabel(app.MoteurElectriquePanel);
            app.TemperatureMoteurelecLabel.Text = 'Temperature Moteur elec';
            app.TensionMoteurelecLabel = uilabel(app.MoteurElectriquePanel);
            app.TensionMoteurelecLabel.Text = 'Tension Moteur elec';
            app.MoteurThermiquePanel = uipanel(app.Tab);
            app.MoteurThermiquePanel.Title = 'Moteur Thermique';
            app.RegimeThermqueLabel = uilabel(app.MoteurThermiquePanel);
            app.RegimeThermqueLabel.Text = {'Regime Thermque'; ''};
            app.TempeauLabel = uilabel(app.MoteurThermiquePanel);
            app.TempeauLabel.Text = {'Temp eau'; ''};
            app.lambdaLabel = uilabel(app.MoteurThermiquePanel);
            app.lambdaLabel.Text = {'lambda'; ''};
            app.TempsInjectionLabel = uilabel(app.MoteurThermiquePanel);
            app.TempsInjectionLabel.Text = 'Temps Injection';
            app.PressionCarbuLabel = uilabel(app.MoteurThermiquePanel);
            app.PressionCarbuLabel.Text = 'Pression Carbu';
            app.TensionLiPoLabel = uilabel(app.MoteurThermiquePanel);
            app.TensionLiPoLabel.Text = 'Tension LiPo';
            app.VitessePanel = uipanel(app.Tab);
            app.VitessePanel.Title = 'Vitesse';
            app.Label = uilabel(app.VitessePanel);
            app.VitesseGaugeLabel = uilabel(app.VitessePanel);
            app.VitesseGaugeLabel.Text = 'Vitesse';
            app.VitesseGauge = uigauge(app.VitessePanel, 'circular');
            app.VitesseGauge.Limits = [0 80];
            app.DistanceparcourueLabel = uilabel(app.VitessePanel);
            app.DistanceparcourueLabel.Text = 'Distance parcourue';
            app.ComServeurPanel = uipanel(app.Tab);
            app.ComServeurPanel.Title = 'Com Serveur';
            app.StartButton = uibutton(app.ComServeurPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ComServeurPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LampLabel = uilabel(app.ComServeurPanel);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.ComServeurPanel);
            app.ConfigurationmoteurPanel = uipanel(app.Tab);
            app.ConfigurationmoteurPanel.Title = 'Configuration moteur';
            app.ModeMoteurLabel = uilabel(app.ConfigurationmoteurPanel);
            app.ModeMoteurLabel.Text = 'Mode Moteur';
            app.PotentiometreKnobLabel = uilabel(app.ConfigurationmoteurPanel);
            app.PotentiometreKnobLabel.Enable = 'off';
            app.PotentiometreKnobLabel.Text = 'Potentiometre';
            app.PotentiometreKnob = uiknob(app.ConfigurationmoteurPanel, 'discrete');
            app.PotentiometreKnob.Items = {'OFF', 'ID', 'E', 'HY', 'TH', 'C', 'R'};
            app.PotentiometreKnob.Enable = 'off';
            app.PotentiometreKnob.Value = 'OFF';
            app.InfoenvoyerPanel = uipanel(app.Tab);
            app.InfoenvoyerPanel.Title = 'Info à envoyer';
            app.EnvoyerButton = uibutton(app.InfoenvoyerPanel, 'push');
            app.EnvoyerButton.ButtonPushedFcn = createCallbackFcn(app, @EnvoyerButtonPushed2, true);
            app.EnvoyerButton.Text = 'Envoyer';
            app.TurnRegenEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.TurnRegenEditFieldLabel.Text = 'Turn Regen';
            app.TurnRegenEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.TurnRegenEditField.Value = 0.46;
            app.TargetSpeedEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.TargetSpeedEditFieldLabel.Text = 'Target Speed';
            app.TargetSpeedEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.TargetSpeedEditField.Value = 0.46;
            app.TurnICEEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.TurnICEEditFieldLabel.Text = 'Turn ICE';
            app.TurnICEEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.TurnICEEditField.Value = 0.46;
            app.racedeltaEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.racedeltaEditFieldLabel.Text = 'race delta';
            app.racedeltaEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.racedeltaEditField.Value = 0.46;
            app.livedeltaEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.livedeltaEditFieldLabel.Text = 'live delta';
            app.livedeltaEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.livedeltaEditField.Value = 0.46;
            app.n1deltaEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.n1deltaEditFieldLabel.Text = 'n-1 delta';
            app.n1deltaEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.n1deltaEditField.Value = 0.46;
            app.TargetSOCEditFieldLabel = uilabel(app.InfoenvoyerPanel);
            app.TargetSOCEditFieldLabel.Text = 'Target SOC';
            app.TargetSOCEditField = uieditfield(app.InfoenvoyerPanel, 'numeric');
            app.TargetSOCEditField.Value = 0.46;
            app.ConsommationPanel = uipanel(app.Tab);
            app.ConsommationPanel.Title = 'Consommation';
            app.ConsommationCarburantLabel = uilabel(app.ConsommationPanel);
            app.ConsommationCarburantLabel.Text = 'Consommation Carburant';
            app.ConsommationMoteurElecLabel = uilabel(app.ConsommationPanel);
            app.ConsommationMoteurElecLabel.Text = 'Consommation Moteur Elec';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GridLayout = uigridlayout(app.Tab2);
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Regime Electrique')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Regime Thermique')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Courant')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Vitesse'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, 'Tension Moteur Elec')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            % Show the figure after all components are created
            app.TelemetriePV3eUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PV3eTelem

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TelemetriePV3eUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TelemetriePV3eUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelemetriePV3eUIFigure)
        end
    end
end